classdef TestGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        Lamp1     matlab.ui.control.Lamp
        Image     matlab.ui.control.Image
    end

    
    properties (Access = private)
        numclicks % Description
    end
   

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.JSDataHTML.Data = 0;
            app.numclicks = 0;
        end

        % Callback function
        function plotJSData(app, event)
           data = app.JSDataHTML.Data;
           app.numclicks = app.numclicks + 1;
           
           for k = 1:length(app.numclicks)
               aline = animatedline(app.UIAxes,'MaximumNumPoints',10000,'Marker','*');
               addpoints(aline,app.numclicks,data(k));
               drawnow
           end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Lamp1 = uilamp(app.UIFigure);
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'empty.jpeg';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TestGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end